classdef DWIfusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DWIFusionUIFigure          matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoadrawMenu                matlab.ui.container.Menu
        LoadresultsMenu            matlab.ui.container.Menu
        ExportresultsMenu          matlab.ui.container.Menu
        ToNiftiMenu                matlab.ui.container.Menu
        ProcessMenu                matlab.ui.container.Menu
        SmoothMenu                 matlab.ui.container.Menu
        LoadMaskMenu               matlab.ui.container.Menu
        AppGrid                    matlab.ui.container.GridLayout
        DetailsTextArea            matlab.ui.control.TextArea
        DetailsTextAreaLabel       matlab.ui.control.Label
        ViewerPanel                matlab.ui.container.Panel
        TopLeft                    matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        MaskedCheckBox             matlab.ui.control.CheckBox
        CalculateButton            matlab.ui.control.Button
        bsplitvalueEditField       matlab.ui.control.NumericEditField
        bsplitvalueEditFieldLabel  matlab.ui.control.Label
        SizeEditFieldLabel         matlab.ui.control.Label
        SizeEditField              matlab.ui.control.EditField
        MethodDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        DWIsEditFieldLabel         matlab.ui.control.Label
        DWIsEditField              matlab.ui.control.EditField
        AnalysisTab                matlab.ui.container.Tab
        ParametersPanel            matlab.ui.container.Panel
        DEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        DstarEditField             matlab.ui.control.NumericEditField
        DstarEditFieldLabel        matlab.ui.control.Label
        fEditField                 matlab.ui.control.NumericEditField
        fEditFieldLabel            matlab.ui.control.Label
        S0EditField                matlab.ui.control.NumericEditField
        S0EditFieldLabel           matlab.ui.control.Label
        ViewPanel                  matlab.ui.container.ButtonGroup
        fDstarbutton               matlab.ui.control.RadioButton
        GetWLWWButton              matlab.ui.control.Button
        SetWLWWButton              matlab.ui.control.Button
        WWTextArea                 matlab.ui.control.TextArea
        WWTextAreaLabel            matlab.ui.control.Label
        WLTextArea                 matlab.ui.control.TextArea
        WLTextAreaLabel            matlab.ui.control.Label
        DefaultWLWWButton          matlab.ui.control.Button
        fButton                    matlab.ui.control.RadioButton
        DButton                    matlab.ui.control.RadioButton
        DstarButton                matlab.ui.control.RadioButton
        S0Button                   matlab.ui.control.RadioButton
        RawButton                  matlab.ui.control.RadioButton
        PlotPanel                  matlab.ui.container.Panel
        BringwindowsfrontButton    matlab.ui.control.Button
        CloseAllWindowsButton      matlab.ui.control.Button
        ShowsignalplotButton       matlab.ui.control.Button
        BottomLeft                 matlab.ui.container.GridLayout
    end


    properties (Access = private)
        bvals       % b-values for diffusion-weighted images
        bvalspath   % file path to b-values
        data        % loaded image data
        datainfo    % NIfTI header of image data
        dataname    % filename of the image data
        dimensions  % size of the data matrix
        fDstar      % f * D* (perfusion-related diffusion) map
        fDWL        % default value for fDstar (Window Level)
        fDWW        % default value for fDstar (Window Width)
        image       % currently displayed image
        mask        % binary mask for the data
        osw         % orthoslice viewer handle
        path        % file path to dataname
        results     % structure/variable for storing results
        sf          % scaling factors
        XYZ         % voxel index of currently shown location
    end

    methods (Access = private)

        function [] = updateui(app)
            % UPDATEUI Updates the user interface
            %
            %   This function:
            %     - Prepares image data and scaling factors
            %     - Creates/recreates the viewer panel
            %     - Initializes the orthoslice viewer
            %     - Configures view orientation and labels
            %     - Attaches listeners if results are available
            %     - Adjusts display ranges based on selected viewing option
            %     - Updates WL/WW display fields
            %     - Enables/disables plotting panel depending on data availability

            % --- Prepare image data and scaling factors
            app.image = permute(app.image, [3 1 2]);                  % Reorder dimensions for orthosliceViewer
            app.sf    = app.datainfo.PixelDimensions([1 3 2]);        % Scale factors (x, y, z)

            % --- Create/recreate Viewer panel
            if ~isequal(app.osw, [])
                delete(app.ViewerPanel);                              % Remove existing panel
                app.ViewerPanel = uipanel(app.AppGrid);               % Create new panel
                app.ViewerPanel.Title = 'Viewer';                     % Panel title
                app.ViewerPanel.Layout.Row = 1;                       % Grid position (row)
                app.ViewerPanel.Layout.Column = 2;                    % Grid position (column)
            end

            % --- Initialize orthoslice viewer
            app.osw = orthosliceViewer(app.image, 'Parent', app.ViewerPanel, ...
                'ScaleFactors', app.sf);

            % --- Configure views and labels
            % Set orientation
            app.osw.Parent.Children(3).View = [0 -90]; % Sagittal
            app.osw.Parent.Children(4).View = [0 -90]; % Coronal
            app.osw.Parent.Children(2).View = [0 -90]; % Axial

            % Axis labels
            app.osw.Parent.Children(3).XLabel.String = "Sag";
            app.osw.Parent.Children(4).XLabel.String = "Cor";
            app.osw.Parent.Children(2).YLabel.String = "Ax";

            % Remove unnecessary labels
            app.osw.Parent.Children(4).YLabel.String = [];
            app.osw.Parent.Children(2).XLabel.String = [];

            % --- Add crosshair movement listener (if results available)
            if numel(app.results) > 0
                addlistener(app.osw, 'CrosshairMoved', ...
                    @(src, evt) movedCrosshairListener(app, src, evt));
            end

            % --- Adjust display range based on selected button
            selectedButton = app.ViewPanel.SelectedObject;
            switch selectedButton
                case app.S0Button
                    app.osw.DisplayRange = round(app.osw.DisplayRange);

                case app.fButton
                    app.osw.DisplayRange = round(app.osw.DisplayRange,2);

                case app.DstarButton
                    app.osw.DisplayRange = [0 0.03];

                case app.DButton
                    app.osw.DisplayRange = round(app.osw.DisplayRange,3);

                case app.RawButton
                    app.osw.DisplayRange = round(app.osw.DisplayRange);

                case app.fDstarbutton
                    app.setWindowedDisplayRange(app.fDWL, app.fDWW);
            end

            % --- Update default WL/WW button and text fields
            app.DefaultWLWWButton.UserData = app.osw.DisplayRange;
            app.getWLWW;

            % --- Enable/disable plotting panel depending on data availability
            if and(~isequal(app.data, []), ~isequal(app.results, []))
                app.PlotPanel.Enable = true;
            else
                app.PlotPanel.Enable = false;
            end
        end

        function [] = setWindowedDisplayRange(app,WL,WW)
            lower=WL-0.5*WW;
            upper=WL+0.5*WW;
            app.osw.DisplayRange=[lower upper];
        end

        function [WL, WW] = getWLWW(app)
            WW=app.osw.DisplayRange(2)-app.osw.DisplayRange(1);
            WL=(app.osw.DisplayRange(1)+app.osw.DisplayRange(2))/2;
            app.WWTextArea.Value=num2str(WW);
            app.WLTextArea.Value=num2str(WL);
        end

        function movedCrosshairListener(app, ~, evt)
            evname = evt.EventName;
            switch evname
                case 'CrosshairMoved'
                    app.updateIVIMResultsDisplay();
            end
        end
        function [] = updateIVIMResultsDisplay(app)

            indexes=app.osw.SliceNumbers([1 3 2]);
            X=round(indexes(1)*app.sf(1)/app.sf(1));
            Y=round(indexes(2)*app.sf(1)/app.sf(3));
            Z=round(indexes(3)*app.sf(1)/app.sf(2));
            app.S0EditField.Value=app.results(X,Y,Z, 1);
            app.fEditField.Value=app.results(X,Y,Z, 2);
            app.DstarEditField.Value=app.results(X,Y,Z, 3);
            app.DEditField.Value=app.results(X,Y,Z,4);
            app.XYZ=[X,Y,Z];
        end

        function [] = updateDetails(app,text)
            app.DetailsTextArea.Value=[string(datetime('now')) + " "...
                + text; app.DetailsTextArea.Value];

        end

        function [] = clearDetails(app)
            app.DetailsTextArea.Value = [];
        end

        function [] = enableIvimViewing(app)
            app.DstarButton.Enable=true;
            app.fButton.Enable=true;
            app.DButton.Enable=true;
            app.S0Button.Enable=true;
            app.fDstarbutton.Enable=true;
        end
        function [] = disableIvimViewing(app)
            app.DstarButton.Enable=false;
            app.fButton.Enable=false;
            app.DButton.Enable=false;
            app.S0Button.Enable=false;
            app.fDstarbutton.Enable=false;
        end
        function [] = preparefDstar(app)
            app.fDstar=app.results(:,:,:,2).*app.results(:,:,:,3);
            app.fDWL=round(mean(app.fDstar(app.fDstar>0),"all"),4);
            app.fDWW=2*app.fDWL;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            close all;
            clc;
            app.TopLeft.AutoResizeChildren=false;
        end

        % Menu selected function: LoadrawMenu
        function LoadrawMenuSelected(app, event)
            if ~isequal(app.results,[])
                choice = questdlg(['Do you want to load raw data for currently loaded results ' ...
                    'or a new dataset?'], 'Warning', ...
                    'Current','New', 'Cancel','Current');
                if or(isequal(choice,'Cancel'),isempty(choice))
                    figure(app.DWIFusionUIFigure);
                    return
                end
            else
                choice='New';
            end


            try
                errorstring="Error loading nii file!";
                [app.dataname,app.path] = uigetfile({'*.nii;*.nii.gz','Nifti Files'});
                app.DWIsEditField.Value=app.dataname;
                app.data=niftiread(strcat(app.path,app.dataname));
                app.datainfo=niftiinfo(strcat(app.path,app.dataname));
                app.dimensions=size(app.data);
            catch
                app.data=[];
                waitfor(msgbox("Error loading nii file!","Warning",'warn'));
                figure(app.DWIFusionUIFigure);
                return
            end

            switch choice
                case 'Current'
                    try
                        app.updateDetails('Loading raw data to results...');
                        if ~(app.dimensions(1:3)==size(app.results,1:3))
                            errorstring="Unable to load data, size of image is different than results";
                            error(errorstring);
                        end
                    catch
                        waitfor(msgbox(errorstring,"Warning",'warn'));
                        figure(app.DWIFusionUIFigure);
                        return
                    end
                    last_pos=app.osw.SliceNumbers;
                case 'New'
                    app.results=[];
                    app.updateDetails('Loading new raw data...');
                    app.S0EditField.Value=0;
                    app.fEditField.Value=0;
                    app.DstarEditField.Value=0;
                    app.DEditField.Value=0;
                    app.disableIvimViewing();
            end
            %load bvals
            try
                errorstring="Bvals not found! Please select bvals manually";
                name=split(app.dataname,".");
                app.bvalspath=strcat(app.path,name(1),".bval");
                app.bvals=load(app.bvalspath);
                app.CalculateButton.Enable=true;
            catch
                waitfor(msgbox(errorstring,"Warning",'warn'));
                [name,app.path] = uigetfile({'*.bval;*.txt','b-values'});
                try
                    app.bvalspath=strcat(app.path,name);
                    if isequal(name,0)
                        errorstring="Bvals file not selected!";
                        error(errorstring)
                    end
                    app.bvals=load(app.bvalspath);
                    if ~isequal(numel(app.bvals),app.dimensions(4))
                        errorstring=sprintf('Bvals length (%d) does not match number of volumes (%d).', ...
                            numel(app.bvals), app.dimensions(4));
                        app.bvals=[];
                        error(errorstring);%#ok<SPERR>
                    end
                    
                catch
                    app.data=[];
                    app.CalculateButton.Enable=false;
                    waitfor(msgbox(errorstring,"Warning",'warn'));
                    figure(app.DWIFusionUIFigure);
                    return
                end
            end


            app.mask=[];
            app.MaskedCheckBox.Value=false;
            app.ViewPanel.SelectedObject=app.RawButton;
            bzero=find(app.bvals==0);
            app.image=squeeze(app.data(:,:,:,bzero(1)));
            app.updateDetails("Succesfully loaded data");

            app.SizeEditField.Value=num2str(app.dimensions);

            app.MaskedCheckBox.Enable=false;
            app.LoadMaskMenu.Enable=true;
            app.SmoothMenu.Enable=true;
            app.TopLeft.Visible=true;
            app.RawButton.Enable=true;
            app.CalculateButton.Enable=true;
            app.DWIsEditFieldLabel.Text="DWIs";
            app.ViewerPanel.Visible=true;
            app.updateui()
            if isequal(choice,'Current')
            app.osw.SliceNumbers=last_pos;
            
            end
            figure(app.DWIFusionUIFigure);

        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if(isequal(value,'1step'))
                app.bsplitvalueEditField.Enable=false;
                app.bsplitvalueEditFieldLabel.Enable=false;
            else
                app.bsplitvalueEditField.Enable=true;
                app.bsplitvalueEditFieldLabel.Enable=true;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            if app.MaskedCheckBox.Value==false
                choice = questdlg('Computing without mask takes long time. Do you wish to proceed?', ...
                    'Warning', ...
                    'Yes','No','No');  % Default is 'No'
                switch choice
                    case 'Yes'
                        app.updateDetails('Proceeding without mask...');
                    case 'No'
                        app.updateDetails('Aborted');
                        figure(app.DWIFusionUIFigure);
                        return
                    case []
                        app.updateDetails('Aborted');
                        figure(app.DWIFusionUIFigure);
                        return
                end
            end
            app.updateDetails("Calculating, please wait")
            pause(0.05);
            try
                app.results=get_ivim_v5(app.bvalspath,app.data,method=app.MethodDropDown.Value,bsplit=app.bsplitvalueEditField.Value,mask=app.mask);
                app.updateDetails("Calculated")
                app.ViewPanel.Enable=true;
                app.image=app.results(:,:,:,1);
                app.ViewPanel.SelectedObject=app.S0Button;
                app.ExportresultsMenu.Enable=true;
                app.ToNiftiMenu.Enable=true;
                app.preparefDstar();
                app.enableIvimViewing();
                app.updateui();

                figure(app.DWIFusionUIFigure);
            catch er
                app.updateDetails("Error during calculation, please check the dataset.")
                app.results=[];
                app.fDstar=[];
            end

        end

        % Button pushed function: ShowsignalplotButton
        function ShowSignalPlotButtonPushed(app, event)
            h=findobj(Type='figure',Name=num2str(app.osw.SliceNumbers));
            if isempty(h)
                f=app.results(app.XYZ(1),app.XYZ(2),app.XYZ(3),2);
                if f==0
                    waitfor(warndlg("No results for this point!"));
                    figure(app.DWIFusionUIFigure);
                    return
                end
                set(0,"DefaultLineLineWidth",1.2);
                set(0,"DefaultAxesFontSize",12);
                h=figure(Name=num2str(app.osw.SliceNumbers));
                ax=axes(h);
                plot(ax,app.bvals,squeeze(app.data(app.XYZ(1),app.XYZ(2),app.XYZ(3),:)),'+',"DisplayName","Acquired signal");
                hold on
                grid on
                S0=app.results(app.XYZ(1),app.XYZ(2),app.XYZ(3),1);
                Dstar=app.results(app.XYZ(1),app.XYZ(2),app.XYZ(3),3);
                D=app.results(app.XYZ(1),app.XYZ(2),app.XYZ(3),4);
                b=linspace(min(app.bvals),max(app.bvals),200);
                ivim=f.*S0.*exp(-b.*Dstar);
                plotIVCM=false; % set this to true if IVCM plot desired.
                if(plotIVCM)
                    ldelta=0.04;
                    delta=0.02;
                    c=ldelta*sqrt(b./(ldelta-delta/3));
                    ivim=f.*S0.*exp(-c.*Dstar);%IVCM
                else
                    if Dstar>0.05
                        waitfor(warndlg("The Dstar parameter is very high,\n the fit might be wrong (Do you try to visualise IVCM?)"));
                    end
                end
                nonivim=(1-f).*S0*exp(-b*D);
                plot(ax,b,nonivim,"DisplayName","DWI reconstruction");
                plot(ax,b,ivim+nonivim,"DisplayName","IVIM reconstruction");
                legend();

            else
                figure(h);
            end
        end

        % Menu selected function: ToNiftiMenu
        function ToNiftiMenuSelected(app, event)
            app.updateDetails("Exporting data to NIfTI")
            savepath=uigetdir();
            filename=inputdlg("Enter filename","Filename",[1 35],strcat(app.dataname,"_IVIMresults.nii"));
            app.datainfo.ImageSize=size(app.results);
            app.datainfo.Datatype=class(app.results);
            niftiwrite(app.results,strcat(savepath,'/',filename{1}),app.datainfo);
        end

        % Menu selected function: LoadMaskMenu
        function LoadMaskMenuSelected(app, event)
            [maskname,app.path] = uigetfile({'*.nii;*.nii.gz','Nifti Files'});
            try
                app.mask= niftiread(app.path +"/"+maskname);
                updateDetails(app,"Loaded mask");
                app.MaskedCheckBox.Value=true;
            catch
                updateDetails(app,"Failed to load mask");
            end
            figure(app.DWIFusionUIFigure);
        end

        % Selection changed function: ViewPanel
        function ViewPanelSelectionChanged(app, event)
            selectedButton = app.ViewPanel.SelectedObject;
            switch selectedButton
                case app.S0Button
                    app.image=app.results(:,:,:,1);
                case app.fButton
                    app.image=app.results(:,:,:,2);
                case app.DstarButton
                    app.image=app.results(:,:,:,3);
                case app.DButton
                    app.image=app.results(:,:,:,4);
                case app.RawButton
                    bzero=find(app.bvals==0);
                    app.image=app.data(:,:,:,bzero(1));
                case app.fDstarbutton
                    app.image=app.fDstar;
            end
            last_pos=app.osw.SliceNumbers;
            app.updateui();
            app.osw.SliceNumbers=last_pos;
        end

        % Menu selected function: LoadresultsMenu
        function LoadresultsMenuSelected(app, event)
            errorstring="Error loading nii file!";
            if ~isequal(app.data,[])
                choice = questdlg(['Do you want to load results of calculations on currently loaded data ' ...
                    'or a new dataset?'], 'Warning', ...
                    'Current','New','Cancel','New');
                if or(isequal(choice,'Cancel'),isempty(choice))
                    figure(app.DWIFusionUIFigure);
                    return
                end
                app.disableIvimViewing();
            else
                choice='New';
            end
            

            try
                errorstring="Error loading nii file!";
                [dataname,path] = uigetfile({'*.nii;*.nii.gz','Nifti Files'}); %#ok<ADPROPLC>
                app.results=niftiread(strcat(path,dataname)); %#ok<ADPROPLC>
                dimensions=size(app.results);
                if dimensions(4)~=4
                    errorstring="Invalid results dimensions";
                    error(errorstring);
                else
                    app.dimensions=dimensions;
                end
                app.datainfo=niftiinfo(strcat(path,dataname)); %#ok<ADPROPLC>
            catch
                waitfor(warndlg(errorstring));
                app.results=[];
                figure(app.DWIFusionUIFigure);
                return
            end
    
            switch choice
                case 'Current'
                    app.updateDetails('Loading calculated data for current raw data ...');
                    try
                        if ~(app.dimensions(1:3)==size(app.data,1:3))
                            errorstring="Unable to load data, size of image is different than results";
                            error(errorstring);
                        end
                    catch
                        waitfor(msgbox(errorstring,"Warning",'warn'));
                        figure(app.DWIFusionUIFigure);
                        return
                    end
                    last_pos=app.osw.SliceNumbers;
                case 'New'
                    app.updateDetails('Loading new calculated data...');
                    app.fDstar=[];
                    app.data=[];
                    app.RawButton.Enable=false;
                    app.CalculateButton.Enable=false;
                case 'Cancel'
                    return
            end
            app.DWIsEditField.Value=dataname; %#ok<ADPROPLC>
            app.DWIsEditFieldLabel.Text="IVIM file";

            app.image=squeeze(app.results(:,:,:,1));
            app.TopLeft.Visible=true;
            app.updateDetails("Succesfully loaded data");
            app.ViewPanel.SelectedObject=app.S0Button;
            app.SizeEditField.Value=num2str(app.dimensions);
            app.preparefDstar();
            app.enableIvimViewing();
            app.updateui();
            if isequal(choice,'Current')
            app.osw.SliceNumbers=last_pos;
            end
            app.updateIVIMResultsDisplay();
            app.ViewerPanel.Visible=true;
            figure(app.DWIFusionUIFigure);
            app.TopLeft.SelectedTab=app.AnalysisTab;
        end

        % Button pushed function: DefaultWLWWButton
        function DefaultWLWWButtonPushed(app, event)
            app.osw.DisplayRange=app.DefaultWLWWButton.UserData;
            WW=app.DefaultWLWWButton.UserData(2)-app.DefaultWLWWButton.UserData(1);
            WL=(app.DefaultWLWWButton.UserData(1)+app.DefaultWLWWButton.UserData(2))/2;
            app.WWTextArea.Value=num2str(WW);
            app.WLTextArea.Value=num2str(WL);
        end

        % Button pushed function: SetWLWWButton
        function SetWLWWButtonPushed(app, event)
            WL=str2double(app.WLTextArea.Value);
            WW=str2double(app.WWTextArea.Value);
            app.setWindowedDisplayRange(WL,WW);
        end

        % Button pushed function: GetWLWWButton
        function GetWLWWButtonPushed(app, event)
            app.getWLWW();
        end

        % Button pushed function: CloseAllWindowsButton
        function CloseAllWindowsButtonPushed(app, event)
            close all
        end

        % Button pushed function: BringwindowsfrontButton
        function BringwindowsfrontButtonPushed(app, event)
            handle_set = findobj('Type', 'Figure');
            n_figures=numel(handle_set);
            numbers=zeros(1,n_figures);
            if n_figures>0
                for i=1:n_figures
                    numbers(i)=handle_set(i).Number;
                end
                numbers=sort(numbers);
                for i=1:n_figures
                    figure(numbers(i))
                end
            else
                return
            end
        end

        % Menu selected function: SmoothMenu
        function SmoothMenuSelected(app, event)
            sigma = 1; % The standard deviation of the Gaussian kernel
            smoothedData = zeros(size(app.data));

            for b = 1:size(app.data, 4)
                % Extract the 3D volume for the current b-value
                volume = app.data(:,:,:,b);

                % Apply 3D Gaussian smoothing
                % 'FilterSize' is optional; default is 2*ceil(2*sigma)+1
                smoothedData(:,:,:,b) = imgaussfilt3(volume, sigma);
            end
            app.data=smoothedData;
            bzero=find(app.bvals==0);
            app.image=app.data(:,:,:,bzero(1));
            last_pos=app.osw.SliceNumbers;
            app.updateui();
            app.osw.SliceNumbers=last_pos;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DWIFusionUIFigure = uifigure('Visible', 'off');
            app.DWIFusionUIFigure.Name = 'DWIFusion';
            app.FileMenu = uimenu(app.DWIFusionUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadrawMenu = uimenu(app.FileMenu);
            app.LoadrawMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadrawMenuSelected, true);
            app.LoadrawMenu.Text = 'Load raw';
            app.LoadresultsMenu = uimenu(app.FileMenu);
            app.LoadresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadresultsMenuSelected, true);
            app.LoadresultsMenu.Text = 'Load results';
            app.ExportresultsMenu = uimenu(app.FileMenu);
            app.ExportresultsMenu.Enable = 'off';
            app.ExportresultsMenu.Text = 'Export results';
            app.ToNiftiMenu = uimenu(app.ExportresultsMenu);
            app.ToNiftiMenu.MenuSelectedFcn = createCallbackFcn(app, @ToNiftiMenuSelected, true);
            app.ToNiftiMenu.Enable = 'off';
            app.ToNiftiMenu.Text = 'To Nifti';
            app.ProcessMenu = uimenu(app.DWIFusionUIFigure);
            app.ProcessMenu.Text = 'Process';
            app.SmoothMenu = uimenu(app.ProcessMenu);
            app.SmoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothMenuSelected, true);
            app.SmoothMenu.Enable = 'off';
            app.SmoothMenu.Text = 'Smooth';
            app.LoadMaskMenu = uimenu(app.ProcessMenu);
            app.LoadMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMaskMenuSelected, true);
            app.LoadMaskMenu.Enable = 'off';
            app.LoadMaskMenu.Text = 'Load mask';
            app.AppGrid = uigridlayout(app.DWIFusionUIFigure);
            app.BottomLeft = uigridlayout(app.AppGrid);
            app.TopLeft = uitabgroup(app.AppGrid);
            app.TopLeft.Visible = 'off';
            app.DataTab = uitab(app.TopLeft);
            app.DataTab.Title = 'Data';
            app.DWIsEditField = uieditfield(app.DataTab, 'text');
            app.DWIsEditField.Editable = 'off';
            app.DWIsEditFieldLabel = uilabel(app.DataTab);
            app.DWIsEditFieldLabel.Text = 'DWIs';
            app.MethodDropDown = uidropdown(app.DataTab);
            app.MethodDropDown.Items = {'1step', 'segmented', 'grid', 'IVCM_grid'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'segmented';
            app.MethodDropDownLabel = uilabel(app.DataTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.SizeEditField = uieditfield(app.DataTab, 'text');
            app.SizeEditField.Editable = 'off';
            app.SizeEditFieldLabel = uilabel(app.DataTab);
            app.SizeEditFieldLabel.Text = 'Size';
            app.bsplitvalueEditFieldLabel = uilabel(app.DataTab);
            app.bsplitvalueEditFieldLabel.Text = 'b-split value';
            app.bsplitvalueEditField = uieditfield(app.DataTab, 'numeric');
            app.bsplitvalueEditField.Value = 250;
            app.CalculateButton = uibutton(app.DataTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.MaskedCheckBox = uicheckbox(app.DataTab);
            app.MaskedCheckBox.Tooltip = {'Automatically checks if loaded mask trough Process->Load mask'};
            app.MaskedCheckBox.Enable = 'off';
            app.MaskedCheckBox.Text = 'Masked';
            app.AnalysisTab = uitab(app.TopLeft);
            app.AnalysisTab.Title = 'Analysis';
            app.PlotPanel = uipanel(app.AnalysisTab);
            app.PlotPanel.Enable = 'off';
            app.PlotPanel.Title = 'Plot';
            app.ShowsignalplotButton = uibutton(app.PlotPanel, 'push');
            app.ShowsignalplotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSignalPlotButtonPushed, true);
            app.ShowsignalplotButton.Text = 'Show signal plot';
            app.CloseAllWindowsButton = uibutton(app.PlotPanel, 'push');
            app.CloseAllWindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllWindowsButtonPushed, true);
            app.CloseAllWindowsButton.Text = 'Close all windows';
            app.BringwindowsfrontButton = uibutton(app.PlotPanel, 'push');
            app.BringwindowsfrontButton.ButtonPushedFcn = createCallbackFcn(app, @BringwindowsfrontButtonPushed, true);
            app.BringwindowsfrontButton.Text = 'Bring windows front';
            app.ViewPanel = uibuttongroup(app.AnalysisTab);
            app.ViewPanel.SelectionChangedFcn = createCallbackFcn(app, @ViewPanelSelectionChanged, true);
            app.ViewPanel.Title = 'View';
            app.RawButton = uiradiobutton(app.ViewPanel);
            app.RawButton.Text = '$Raw$';
            app.RawButton.Interpreter = 'latex';
            app.S0Button = uiradiobutton(app.ViewPanel);
            app.S0Button.Text = '$S_0$';
            app.S0Button.Interpreter = 'latex';
            app.DstarButton = uiradiobutton(app.ViewPanel);
            app.DstarButton.Text = '$D^*$';
            app.DstarButton.Interpreter = 'latex';
            app.DButton = uiradiobutton(app.ViewPanel);
            app.DButton.Text = '$D$';
            app.DButton.Interpreter = 'latex';
            app.fButton = uiradiobutton(app.ViewPanel);
            app.fButton.Text = '$f$';
            app.fButton.Interpreter = 'latex';
            app.fButton.Value = true;
            app.DefaultWLWWButton = uibutton(app.ViewPanel, 'push');
            app.DefaultWLWWButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultWLWWButtonPushed, true);
            app.DefaultWLWWButton.Text = {'Default '; 'WL/WW'};
            app.WLTextAreaLabel = uilabel(app.ViewPanel);
            app.WLTextAreaLabel.Text = 'WL';
            app.WLTextArea = uitextarea(app.ViewPanel);
            app.WWTextAreaLabel = uilabel(app.ViewPanel);
            app.WWTextAreaLabel.Text = 'WW';
            app.WWTextArea = uitextarea(app.ViewPanel);
            app.SetWLWWButton = uibutton(app.ViewPanel, 'push');
            app.SetWLWWButton.ButtonPushedFcn = createCallbackFcn(app, @SetWLWWButtonPushed, true);
            app.SetWLWWButton.Text = {'Set '; 'WL/WW'};
            app.GetWLWWButton = uibutton(app.ViewPanel, 'push');
            app.GetWLWWButton.ButtonPushedFcn = createCallbackFcn(app, @GetWLWWButtonPushed, true);
            app.GetWLWWButton.Text = {'Get '; 'WL/WW'};
            app.fDstarbutton = uiradiobutton(app.ViewPanel);
            app.fDstarbutton.Enable = 'off';
            app.fDstarbutton.Text = '$f\cdot D^*$';
            app.fDstarbutton.Interpreter = 'latex';
            app.ParametersPanel = uipanel(app.AnalysisTab);
            app.ParametersPanel.Title = 'Parameters';
            app.S0EditFieldLabel = uilabel(app.ParametersPanel);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.ParametersPanel, 'numeric');
            app.S0EditField.Editable = 'off';
            app.fEditFieldLabel = uilabel(app.ParametersPanel);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.fEditField.Editable = 'off';
            app.DstarEditFieldLabel = uilabel(app.ParametersPanel);
            app.DstarEditFieldLabel.Text = 'Dstar';
            app.DstarEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DstarEditField.Editable = 'off';
            app.DEditFieldLabel = uilabel(app.ParametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DEditField.Editable = 'off';
            app.ViewerPanel = uipanel(app.AppGrid);
            app.ViewerPanel.Title = 'Viewer';
            app.ViewerPanel.Visible = 'off';
            app.DetailsTextAreaLabel = uilabel(app.AppGrid);
            app.DetailsTextAreaLabel.Text = 'Details';
            app.DetailsTextArea = uitextarea(app.AppGrid);
            app.DetailsTextArea.Editable = 'off';
            app.DetailsTextArea.Value = {'Welcome to DWIFusion, please load data to begin.'};
            % Show the figure after all components are created
            app.DWIFusionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DWIfusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DWIFusionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DWIFusionUIFigure)
        end
    end
end